classdef DynamicsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        FtipYaw              matlab.ui.control.Spinner
        YawSpinner_2Label    matlab.ui.control.Label
        FtipZ                matlab.ui.control.Spinner
        ZSpinner_2Label      matlab.ui.control.Label
        FtipPitch            matlab.ui.control.Spinner
        PitchSpinner_2Label  matlab.ui.control.Label
        FtipRoll             matlab.ui.control.Spinner
        RollSpinner_2Label   matlab.ui.control.Label
        FtipY                matlab.ui.control.Spinner
        YSpinner_2Label      matlab.ui.control.Label
        FtipX                matlab.ui.control.Spinner
        XSpinner_2Label      matlab.ui.control.Label
        YawSpinner           matlab.ui.control.Spinner
        YawSpinnerLabel      matlab.ui.control.Label
        ZSpinner             matlab.ui.control.Spinner
        ZSpinnerLabel        matlab.ui.control.Label
        PitchSpinner         matlab.ui.control.Spinner
        PitchSpinnerLabel    matlab.ui.control.Label
        RollSpinner          matlab.ui.control.Spinner
        RollSpinnerLabel     matlab.ui.control.Label
        YSpinner             matlab.ui.control.Spinner
        YSpinnerLabel        matlab.ui.control.Label
        XSpinner             matlab.ui.control.Spinner
        XSpinnerLabel        matlab.ui.control.Label
        TorqueGraph          matlab.ui.control.UIAxes
        AccelGraph           matlab.ui.control.UIAxes
        VelocityGraph        matlab.ui.control.UIAxes
        PositionGraph        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path = addpath('/lib/');
        robot % Description
        S = [0 0 1 0 0 0;
             0 -1 0 -cross([0 -1 0], [0 0 0.089459]);
             0 -1 0 -cross([0 -1 0], [-0.425 0 0.089459]);
             0 -1 0 -cross([0 -1 0], [-0.8173 0 0.089459]);
             0 0 -1 -cross([0 0 -1], [-0.8173 -0.10915 0]);
             0 -1 0 -cross([0 -1 0], [-0.8173 0 -0.0052])]'
        
        % Home configuration
        M = [1	0	0	-0.817;
            0	0	-1	-0.19;
            0	1	0	-0.005;
            0	0	0	1];
        n = 6;
        
        currentPose;
        currentQ;

        Mlist;
        Glist;
        g = [0 0 -9.81];

        Ftip;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % addpath('../lib');

            app.currentQ = [0 0 0 0 0 0];
            app.currentPose = MatrixLog6(fkine(app.S, app.M, app.currentQ, 'space'));
            app.currentPose = [app.currentPose(3,2) app.currentPose(1,3) app.currentPose(2,1) app.currentPose(1:3,4)']';

            mdl_ur5;
            app.robot = ur5;
            app.robot.plot(zeros(1,6))

            [app.Mlist, app.Glist] = make_dynamics_model(app.robot);

            app.Ftip = [0 0 0 0 0 0];
        end

        % Value changed function: FtipPitch, FtipRoll, FtipX, FtipY, 
        % ...and 8 other components
        function SpinnerValueChanged(app, event)
            x = app.XSpinner.Value;
            y = app.YSpinner.Value;
            z = app.ZSpinner.Value;
            roll = app.RollSpinner.Value;
            pitch = app.PitchSpinner.Value;
            yaw = app.YawSpinner.Value;

            fx = app.FtipX.Value;
            fy = app.FtipY.Value;
            fz = app.FtipZ.Value;
            froll = app.FtipRoll.Value;
            fpitch = app.FtipPitch.Value;
            fyaw = app.FtipYaw.Value;
            app.Ftip = [fx fy fz froll fpitch fyaw];
            
            psi = yaw; theta = pitch; phi = roll;
            p = [x; y; z];
            R = [cos(theta)*cos(psi) -cos(phi)*sin(psi)+sin(phi)*sin(theta)*cos(psi) sin(phi)*sin(psi)+cos(phi)*sin(theta)*cos(psi);
                 cos(theta)*sin(psi) cos(phi)*cos(psi)+sin(phi)*sin(theta)*sin(psi) -sin(phi)*cos(psi)+cos(phi)*sin(theta)*sin(psi);
                 -sin(theta)         sin(phi)*cos(theta)                            cos(phi)*cos(theta)];
            T = [R p; 0 0 0 1];
            targetPose = MatrixLog6(T);
            targetPose = [targetPose(3,2) targetPose(1,3) targetPose(2,1) targetPose(1:3,4)']';
            
            goalQ = ikin(app.S, app.M, app.currentQ, targetPose);
            tMax = norm(targetPose - app.currentPose);
            
            [tau, jointPos, jointVel, jointAcc, t] = simulate(app.n, app.currentQ, goalQ, app.g, app.S, app.Mlist, app.Glist, tMax, app.Ftip);

            app.robot.plot(jointPos(:,1:100:end)','trail',{'r', 'LineWidth', 2});
            app.currentQ = jointPos(:, end)';
            app.currentPose = MatrixLog6(fkine(app.S, app.M, app.currentQ, 'space'));
            app.currentPose = [app.currentPose(3,2) app.currentPose(1,3) app.currentPose(2,1) app.currentPose(1:3,4)']';
            
            plot(app.TorqueGraph, t, tau);
            plot(app.PositionGraph, t, jointPos);
            plot(app.VelocityGraph, t, jointVel);
            plot(app.AccelGraph, t, jointAcc);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PositionGraph = uiaxes(app.UIFigure);
            title(app.PositionGraph, 'Joint Positions')
            xlabel(app.PositionGraph, 'time (s)')
            ylabel(app.PositionGraph, 'position (rad)')
            zlabel(app.PositionGraph, 'Z')
            app.VelocityGraph = uiaxes(app.UIFigure);
            title(app.VelocityGraph, 'Joint Velocites')
            xlabel(app.VelocityGraph, 'time (s)')
            ylabel(app.VelocityGraph, 'velocity (rad/s)')
            zlabel(app.VelocityGraph, 'Z')
            app.AccelGraph = uiaxes(app.UIFigure);
            title(app.AccelGraph, 'Joint Accelerations')
            xlabel(app.AccelGraph, 'time (s)')
            ylabel(app.AccelGraph, 'accel (rad/s^2)')
            zlabel(app.AccelGraph, 'Z')
            app.TorqueGraph = uiaxes(app.UIFigure);
            title(app.TorqueGraph, 'Joint Torqures')
            xlabel(app.TorqueGraph, 'time (s)')
            ylabel(app.TorqueGraph, 'torque (Nm)')
            zlabel(app.TorqueGraph, 'Z')
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.Step = 0.01;
            app.XSpinner.Limits = [-1 1];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.Step = 0.01;
            app.YSpinner.Limits = [-1 1];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RollSpinnerLabel = uilabel(app.UIFigure);
            app.RollSpinnerLabel.Text = 'Roll';
            app.RollSpinner = uispinner(app.UIFigure);
            app.RollSpinner.Step = 0.01;
            app.RollSpinner.Limits = [-3.14159265358979 3.14159265358979];
            app.RollSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.PitchSpinnerLabel = uilabel(app.UIFigure);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.PitchSpinner = uispinner(app.UIFigure);
            app.PitchSpinner.Step = 0.01;
            app.PitchSpinner.Limits = [-3.14159265358979 3.14159265358979];
            app.PitchSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.UIFigure);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.Step = 0.01;
            app.ZSpinner.Limits = [-1 1];
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.YawSpinnerLabel = uilabel(app.UIFigure);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.UIFigure);
            app.YawSpinner.Step = 0.01;
            app.YawSpinner.Limits = [-3.14159265358979 3.14159265358979];
            app.YawSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.XSpinner_2Label = uilabel(app.UIFigure);
            app.XSpinner_2Label.Text = 'X';
            app.FtipX = uispinner(app.UIFigure);
            app.FtipX.Step = 0.1;
            app.FtipX.Limits = [-10 10];
            app.FtipX.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.YSpinner_2Label = uilabel(app.UIFigure);
            app.YSpinner_2Label.Text = 'Y';
            app.FtipY = uispinner(app.UIFigure);
            app.FtipY.Step = 0.1;
            app.FtipY.Limits = [-10 10];
            app.FtipY.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RollSpinner_2Label = uilabel(app.UIFigure);
            app.RollSpinner_2Label.Text = 'Roll';
            app.FtipRoll = uispinner(app.UIFigure);
            app.FtipRoll.Step = 0.1;
            app.FtipRoll.Limits = [-10 10];
            app.FtipRoll.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.PitchSpinner_2Label = uilabel(app.UIFigure);
            app.PitchSpinner_2Label.Text = 'Pitch';
            app.FtipPitch = uispinner(app.UIFigure);
            app.FtipPitch.Step = 0.1;
            app.FtipPitch.Limits = [-10 10];
            app.FtipPitch.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ZSpinner_2Label = uilabel(app.UIFigure);
            app.ZSpinner_2Label.Text = 'Z';
            app.FtipZ = uispinner(app.UIFigure);
            app.FtipZ.Step = 0.1;
            app.FtipZ.Limits = [-10 10];
            app.FtipZ.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.YawSpinner_2Label = uilabel(app.UIFigure);
            app.YawSpinner_2Label.Text = 'Yaw';
            app.FtipYaw = uispinner(app.UIFigure);
            app.FtipYaw.Step = 0.1;
            app.FtipYaw.Limits = [-10 10];
            app.FtipYaw.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ftip';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DynamicsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end